classdef FinalHW_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        left_clawSpinnerLabel        matlab.ui.control.Label
        left_clawSpinner             matlab.ui.control.Spinner
        right_clawSpinnerLabel       matlab.ui.control.Label
        right_clawSpinner            matlab.ui.control.Spinner
        baseSpinnerLabel             matlab.ui.control.Label
        baseSpinner                  matlab.ui.control.Spinner
        AextendButton                matlab.ui.control.StateButton
        AretractButton               matlab.ui.control.StateButton
        BextendButton                matlab.ui.control.StateButton
        BretractButton               matlab.ui.control.StateButton
        tailSpinnerLabel             matlab.ui.control.Label
        tailSpinner                  matlab.ui.control.Spinner
        scan_LButton                 matlab.ui.control.Button
        reach_LButton                matlab.ui.control.Button
        stopButton                   matlab.ui.control.StateButton
        RsonarEditFieldLabel         matlab.ui.control.Label
        RsonarEditField              matlab.ui.control.NumericEditField
        scan_RButton                 matlab.ui.control.Button
        reach_RButton                matlab.ui.control.Button
        LsonarEditFieldLabel         matlab.ui.control.Label
        LsonarEditField              matlab.ui.control.NumericEditField
        leftopenButton               matlab.ui.control.Button
        leftcloseButton              matlab.ui.control.Button
        rightopenButton              matlab.ui.control.Button
        rightcloseButton             matlab.ui.control.Button
        tailtoleftButton             matlab.ui.control.Button
        tailtorightButton            matlab.ui.control.Button
        basetomiddleButton           matlab.ui.control.Button
        tailtomiddleButton           matlab.ui.control.Button
        UpdateButton                 matlab.ui.control.Button
        min_disEditFieldLabel        matlab.ui.control.Label
        min_disEditField             matlab.ui.control.NumericEditField
        UploadButton                 matlab.ui.control.Button
        IMU_angleEditFieldLabel      matlab.ui.control.Label
        IMU_angleEditField           matlab.ui.control.NumericEditField
        StartCameraButton            matlab.ui.control.StateButton
        PatternRecognitionButton     matlab.ui.control.StateButton
        CameraResultEditFieldLabel   matlab.ui.control.Label
        CameraResultEditField        matlab.ui.control.EditField
        Raw_img                      matlab.ui.control.Image
        Color_img                    matlab.ui.control.Image
        NextStepButton               matlab.ui.control.Button
        ContinuousRunningButton      matlab.ui.control.StateButton
        CurrentpositionSpinnerLabel  matlab.ui.control.Label
        CurrentpositionSpinner       matlab.ui.control.Spinner
        min_angleEditFieldLabel      matlab.ui.control.Label
        min_angleEditField           matlab.ui.control.NumericEditField
        ButtonEditFieldLabel         matlab.ui.control.Label
        ButtonEditField              matlab.ui.control.NumericEditField
    end

    
    %{
    ME134_FINAL_Monkeybar_robot
    Zechen Wang @12/19/2022
    %}
    methods (Access = private)
        %comm() is used to communicate with ESP
        %send=1:upload matlab data to esp
        %send=0:download esp data and update matlab
        function response = comm(app, send)
            if nargin==1
                send=true;
            end
            if send
                %servo angles are interegated into a long int
                %numbers add 10 to guarantee 2 digits
                %example: -12345678,12 is left claw angle
                %(range:10-99,conrresponding to monitor angle 0-89 and
                %servo angle 145-55); 34 is right claw with servo angle
                %40-130; 56 is tail angle (range:10-99, real:0-178,step:2);
                %-78 is base angle with sign(range:-90-+90,real:0-180)
                angles=(app.left_clawSpinner.Value+10)*1000000+(app.right_clawSpinner.Value+10)*10000+(round(app.tailSpinner.Value/2)+10)*100;
                if app.baseSpinner.Value>=0
                    angles=angles+app.baseSpinner.Value;
                else
                    angles=-angles+app.baseSpinner.Value;
                end
                response=webwrite('http://192.168.4.1/data',angles);
            else
                response=webwrite('http://192.168.4.1/data',0);
            end
            
            response=strsplit(response,',');
            app.left_clawSpinner.Value=145-str2double(response{1,1});
            app.right_clawSpinner.Value=str2double(response{1,2})-40;
            app.baseSpinner.Value=str2double(response{1,3})-95;
            app.tailSpinner.Value=min(str2double(response{1,4}),178);
            if str2double(response{1,5})==-1 %-1 is timeout for sonar (too far)
                app.LsonarEditField.Value=999;
            else
                app.LsonarEditField.Value=str2double(response{1,5});
            end
            if str2double(response{1,6})==-1
                app.RsonarEditField.Value=999;
            else
                app.RsonarEditField.Value=str2double(response{1,6});
            end
            app.IMU_angleEditField.Value=str2double(response{1,7});
            app.ButtonEditField.Value=str2double(response{1,8});
            %pause(0.01);
        end
        
        %This code is from HW4.Rock_paper_scissor
        %mildly modified for better performance
        %It returns if the pattern is found
        function pattern_found = img_check(app)
            global img island_map img_height img_width;
            pattern_found=0;
            url="http://192.168.4.3/capture";
            filename_raw="capture.jpg";
            filename_color="color.jpg";
            color_ratio=1.15;
            color_threshold=60;
            websave(filename_raw,url);
            raw_img=imread(filename_raw);
            app.Raw_img.ImageSource=raw_img;
            
            raw_img=double(raw_img); %uint8 to double
            [r,g,b]=imsplit(raw_img);
            img=raw_img;
            [img_height,img_width,x]=size(raw_img);
            for i=1:img_height
                for j=1:img_width
                    sum_rgb=sum([r(i,j),g(i,j),b(i,j)]);
                    max_rgb=max([r(i,j),g(i,j),b(i,j)]);
                    min_rgb=min([r(i,j),g(i,j),b(i,j)]);
                    mid_rgb=sum_rgb-max_rgb-min_rgb;
                    %if max of rgb is 15%(color_ratio) more
                    %than mid rgb, it's a color pixel
                    %if max of rgb is smaller than
                    %threshold, it's too dark
                    not_white=(max_rgb-color_ratio*mid_rgb)>0 & max_rgb>color_threshold;
                    if(not_white)
                        if(max_rgb==r(i,j)) % leave only the max rgb
                            img(i,j,2)=0;
                            img(i,j,3)=0;
                        elseif(max_rgb==g(i,j))
                            img(i,j,1)=0;
                            img(i,j,3)=0;
                        else
                            img(i,j,2)=0;
                            img(i,j,1)=0;
                        end
                    else %white and dark pixels are black
                        img(i,j,1)=0;
                        img(i,j,2)=0;
                        img(i,j,3)=0;
                    end
                end
            end
            imwrite(img,filename_color);
            
            island_map=zeros(img_height,img_width);
            island_list_r=[];
            
            for i=1:img_height
                for j=1:img_width
                    if (img(i,j,1)~=0 & island_map(i,j)==0) %pixel is a color and not labeled
                        l=size(island_list_r,1)+1;
                        island_list_r(l,1)=l;
                        island_list_r(l,2)=0;
                        label_island(i,j,l); %see function at bottom
                    end
                end
            end
            for i=1:img_height
                for j=1:img_width
                    if island_map(i,j,1)~=0
                        island_list_r(island_map(i,j,1),2)=island_list_r(island_map(i,j,1),2)+1;
                    end
                end
            end
            if size(island_list_r,1)>0
                island_list_r=sortrows(island_list_r,-2);
                r_island=island_list_r(1,1);
                img_r_focus=img;
                for i=1:img_height
                    for j=1:img_width
                        if (island_map(i,j)~=r_island)
                            img_r_focus(i,j,1)=0;
                        end
                    end
                end
                app.Color_img.ImageSource=img_r_focus;
                r_center=[0,0];
                r_count=island_list_r(1,2);
                for i=1:img_height
                    for j=1:img_width
                        if(img_r_focus(i,j,1)~=0)
                            r_center(1,1)=r_center(1,1)+i;
                            r_center(1,2)=r_center(1,2)+j;
                        end
                    end
                end
                
                r_center=round(r_center/r_count);
                if(r_count~=0)
                    if(img(r_center(1),r_center(2),3)~=0)
                        pattern_found=1;
                    end
                end
            end
            
            
            function label_island(x,y,l)
                checklist=[x,y]; %Start point
                island_map(x,y)=l; %label start point
                while(1)
                    if (size(checklist,1)==0) %island fully labeled
                        break;
                    else
                        i=checklist(1,1); %note down the top coordinates
                        j=checklist(1,2);
                        checklist(1,:)=[]; %delete the top entry
                        if(i-1~=0) %pixel above
                            if(img(i-1,j,1)~=0 & island_map(i-1,j)==0) %part of island and not labeled
                                island_map(i-1,j)=l; %label it. Faster than only label it on its turn
                                checklist=[checklist;i-1 j]; %put it on the list
                            end
                        end
                        if(j-1~=0) %left pixel
                            if(img(i,j-1,1)~=0 & island_map(i,j-1)==0)
                                island_map(i,j-1)=l;
                                checklist=[checklist;i j-1];
                            end
                        end
                        if(j+1~=img_width+1) %right pixel
                            if(img(i,j+1,1)~=0 & island_map(i,j+1)==0)
                                island_map(i,j+1)=l;
                                checklist=[checklist;i j+1];
                            end
                        end
                        if(i+1~=img_height+1) %pixel below
                            if(img(i+1,j,1)~=0 & island_map(i+1,j)==0)
                                island_map(i+1,j)=l;
                                checklist=[checklist;i+1 j];
                            end
                        end
                    end
                end
            end
            
        end
        
        %This function is used to scan for bars
        function scan(app,side) %side=1:left,-1:right
            min_angle_avg=0;
            scan_count=0;
            if side==1
                app.left_clawSpinner.Value=89;
            else
                app.right_clawSpinner.Value=89;
            end
            comm(app);
            for j=1:4 %scan four times
                min_dis=9999;
                min_angle=0;
                for i=5:30 %scan range:5~30
                    if mod(j,2)==1 %go downwards
                        angle=i;
                    else %go upwards
                        angle=35-i;
                    end
                    app.baseSpinner.Value=angle*side;
                    response=comm(app);
                    if side==1
                        %use response than edit field value for lower
                        %latency
                        distance=str2double(response{1,5});%app.LsonarEditField.Value;
                    else
                        distance=str2double(response{1,6});%app.RsonarEditField.Value;
                    end
                    
                    if distance==-1
                        distance=999;
                    end
                    
                    if distance< min_dis
                        min_dis=distance;
                        min_angle=angle*side;
                    end
                    app.min_disEditField.Value=min_dis;
                    app.min_angleEditField.Value=min_angle;
                end
                if min_dis<=20 %successful scan
                    min_angle_avg=min_angle_avg+min_angle;
                    scan_count=scan_count+1;
                end
            end
            app.baseSpinner.Value=min_angle_avg/scan_count;
            comm(app);
        end
        
        %This function is used to extend linear actuator to reach the bar
        %and grip it
        function reach(app,side)
            if side==1
                app.left_clawSpinner.Value=89;
            else
                app.right_clawSpinner.Value=89;
            end
            comm(app);
            if side==1
                webwrite('http://192.168.4.1/data',-1); %-1:left extend
            else
                webwrite('http://192.168.4.1/data',-4); %-4:right extend
            end
            count=0;
            %stop button normally doesn't work so we have a count to
            %timeout the while loop
            while(~app.stopButton.Value && count<30)
                response=comm(app,0);
                count=count+1;
                if side==1
                    distance=str2double(response{1,5});
                else
                    distance=str2double(response{1,6});
                end
                
                %Sonar distance measure
                if side==1 && (distance<=6 || app.stopButton.Value)
                    webwrite('http://192.168.4.1/data',-2);
                    app.left_clawSpinner.Value=0;
                    comm(app);
                    break
                end
                if side==-1 && (distance<=6 || app.stopButton.Value)
                    webwrite('http://192.168.4.1/data',-5);
                    app.right_clawSpinner.Value=0;
                    comm(app);
                    break
                end
                %Proximity button switch trigger
                bump_triggered=str2double(response{1,8});
                %if button is triggered, esp will close the claw and we
                %will have a 0 claw angle response
                if bump_triggered || (side==1 && app.left_clawSpinner.Value==0) || (side==-1 && app.right_clawSpinner.Value==0)
                    comm(app,0);
                    app.ButtonEditField.Value=1;
                    if side==1
                        app.left_clawSpinner.Value=0;
                    else
                        app.right_clawSpinner.Value=0;
                    end
                    break
                end
            end
            comm(app,0);
        end
        
        %This function defines what the robot should do next to change to next position according to
        %its current position number
        function pos = next(app)
            pos=app.CurrentpositionSpinner.Value;
            switch pos
                case 0 %Preparatory action, double side hanging at same bar
                    app.left_clawSpinner.Value=0;
                    app.right_clawSpinner.Value=0;
                    app.baseSpinner.Value=0;
                    app.tailSpinner.Value=0;
                    comm(app);
                    webwrite('http://192.168.4.1/data',-3);
                    webwrite('http://192.168.4.1/data',-6);
                    pause(0.5);
                    
                    app.left_clawSpinner.Value=89;
                    comm(app);
                    webwrite('http://192.168.4.1/data',-4);
                    pause(0.3);
                    webwrite('http://192.168.4.1/data',-5);
                case 1 %right side grasp
                    scan(app,1);
                    
                case 2 %right side grasp, left side aimed
                    reach(app,1);
                    
                case 3 %Both grasp, left at front
                    app.tailSpinner.Value=178;
                    app.left_clawSpinner.Value=0;
                    app.right_clawSpinner.Value=0;
                    comm(app);
                    pause(1);
                    app.right_clawSpinner.Value=89;
                    comm(app);
                    pause(0.2);
                    webwrite('http://192.168.4.1/data',-6);
                    pause(0.3);
                    app.baseSpinner.Value=0;
                    comm(app);
                    
                case 4 %left side grasp
                    scan(app,-1);
                    
                case 5 %left side grasp, right side aimed
                    reach(app,-1);
                    
                case 6 %Both grasp, right at front
                    app.tailSpinner.Value=0;
                    app.left_clawSpinner.Value=0;
                    app.right_clawSpinner.Value=0;
                    
                    comm(app);
                    pause(1);
                    app.left_clawSpinner.Value=89;
                    comm(app);
                    pause(0.2);
                    webwrite('http://192.168.4.1/data',-3);
                    pause(0.3);
                    app.baseSpinner.Value=0;
                    comm(app);
            end
            pos=pos+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AextendButton
        function AextendButtonValueChanged(app, event)
            value = app.AextendButton.Value;
            if value
                webwrite('http://192.168.4.1/data',-1);%see definition of these numbers at esp code
            else
                webwrite('http://192.168.4.1/data',-2);
            end
            
        end

        % Value changed function: AretractButton
        function AretractButtonValueChanged(app, event)
            value = app.AretractButton.Value;
            if value
                webwrite('http://192.168.4.1/data',-3);
            else
                webwrite('http://192.168.4.1/data',-2);
            end
        end

        % Value changed function: BextendButton
        function BextendButtonValueChanged(app, event)
            value = app.BextendButton.Value;
            if value
                webwrite('http://192.168.4.1/data',-4);
            else
                webwrite('http://192.168.4.1/data',-5);
            end
        end

        % Value changed function: BretractButton
        function BretractButtonValueChanged(app, event)
            value = app.BretractButton.Value;
            if value
                webwrite('http://192.168.4.1/data',-6);
            else
                webwrite('http://192.168.4.1/data',-5);
            end
        end

        % Value changed function: baseSpinner, left_clawSpinner, 
        % right_clawSpinner, tailSpinner
        function left_clawSpinnerValueChanged(app, event)
            comm(app);
            %This callback function is used for all spinners
        end

        % Button pushed function: scan_LButton
        function scan_LButtonPushed(app, event)
            scan(app,1);
        end

        % Button pushed function: reach_LButton
        function reach_LButtonPushed(app, event)
            reach(app,1);
        end

        % Value changed function: stopButton
        function stopButtonValueChanged(app, event)
            webwrite('http://192.168.4.1/data',-5);
            webwrite('http://192.168.4.1/data',-2);
        end

        % Button pushed function: scan_RButton
        function scan_RButtonPushed(app, event)
            scan(app,-1);
        end

        % Button pushed function: reach_RButton
        function reach_RButtonPushed(app, event)
            reach(app,-1);
        end

        % Button pushed function: leftopenButton
        function leftopenButtonPushed(app, event)
            app.left_clawSpinner.Value=89;
            comm(app);
        end

        % Button pushed function: leftcloseButton
        function leftcloseButtonPushed(app, event)
            app.left_clawSpinner.Value=0;
            comm(app);
        end

        % Button pushed function: rightopenButton
        function rightopenButtonPushed(app, event)
            app.right_clawSpinner.Value=89;
            comm(app);
        end

        % Button pushed function: rightcloseButton
        function rightcloseButtonPushed(app, event)
            app.right_clawSpinner.Value=0;
            comm(app);
        end

        % Button pushed function: tailtoleftButton
        function tailtoleftButtonPushed(app, event)
            app.tailSpinner.Value=178;
            comm(app);
        end

        % Button pushed function: tailtorightButton
        function tailtorightButtonPushed(app, event)
            app.tailSpinner.Value=0;
            comm(app);
        end

        % Button pushed function: basetomiddleButton
        function basetomiddleButtonPushed(app, event)
            app.baseSpinner.Value=0;
            comm(app);
        end

        % Button pushed function: tailtomiddleButton
        function tailtomiddleButtonPushed(app, event)
            app.tailSpinner.Value=90;
            comm(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            comm(app,0);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            comm(app);
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            pos=next(app);
            if pos==7
                pos=1;
            end
            app.CurrentpositionSpinner.Value=pos;
        end

        % Value changed function: StartCameraButton
        function StartCameraButtonValueChanged(app, event)
            value = app.StartCameraButton.Value;
            if value
                while(app.StartCameraButton.Value)
                    %if pattern recognition is required, camera result is
                    %No until the pattern is found
                    if app.PatternRecognitionButton.Value
                        app.CameraResultEditField.Value="No";
                        app.CameraResultEditField.Visible=true;
                    else
                        app.CameraResultEditField.Value="Yes";
                        app.CameraResultEditField.Visible=false;
                    end
                    pattern_found=img_check(app);
                    if pattern_found
                        app.CameraResultEditField.Value="Yes";
                        if app.PatternRecognitionButton.Value
                            %Stop camera since the robot will start running
                            %and camera transmission is heavy load for esp
                            app.StartCameraButton.Value=0;
                            app.PatternRecognitionButton.Value=0;
                            break
                        end
                    end
                    %give esp a big break, too small pause will exaust it
                    %fast and stop sending images
                    pause(1);
                end
            end
        end

        % Value changed function: ContinuousRunningButton
        function ContinuousRunningButtonValueChanged(app, event)
            value = app.ContinuousRunningButton.Value;
            pos=app.CurrentpositionSpinner.Value;
            if value
                while(app.ContinuousRunningButton.Value)
                    comm(app,0);
                    %Pattern recognition only used when pos=0 (preparatory)
                    if pos==0
                        while(app.ContinuousRunningButton.Value)
                            if app.CameraResultEditField.Value=="Yes"
                                break;
                            end
                        end
                    end
                    %only proceed when stable
                    if abs(app.IMU_angleEditField)<=5
                        next(app);
                    else
                        pause(2);
                        if abs(app.IMU_angleEditField)<=5
                            next(app);
                        else
                            break;
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.left_clawSpinnerLabel = uilabel(app.UIFigure);
            app.left_clawSpinnerLabel.Text = 'left_claw';
            app.left_clawSpinner = uispinner(app.UIFigure);
            app.left_clawSpinner.Step = 5;
            app.left_clawSpinner.Limits = [0 89];
            app.left_clawSpinner.ValueChangedFcn = createCallbackFcn(app, @left_clawSpinnerValueChanged, true);
            app.left_clawSpinner.Value = 20;
            app.right_clawSpinnerLabel = uilabel(app.UIFigure);
            app.right_clawSpinnerLabel.Text = 'right_claw';
            app.right_clawSpinner = uispinner(app.UIFigure);
            app.right_clawSpinner.Step = 5;
            app.right_clawSpinner.Limits = [0 89];
            app.right_clawSpinner.ValueChangedFcn = createCallbackFcn(app, @left_clawSpinnerValueChanged, true);
            app.right_clawSpinner.Value = 20;
            app.baseSpinnerLabel = uilabel(app.UIFigure);
            app.baseSpinnerLabel.Text = 'base';
            app.baseSpinner = uispinner(app.UIFigure);
            app.baseSpinner.Step = 5;
            app.baseSpinner.Limits = [-90 90];
            app.baseSpinner.ValueChangedFcn = createCallbackFcn(app, @left_clawSpinnerValueChanged, true);
            app.AextendButton = uibutton(app.UIFigure, 'state');
            app.AextendButton.ValueChangedFcn = createCallbackFcn(app, @AextendButtonValueChanged, true);
            app.AextendButton.Text = 'A extend';
            app.AretractButton = uibutton(app.UIFigure, 'state');
            app.AretractButton.ValueChangedFcn = createCallbackFcn(app, @AretractButtonValueChanged, true);
            app.AretractButton.Text = 'A retract';
            app.BextendButton = uibutton(app.UIFigure, 'state');
            app.BextendButton.ValueChangedFcn = createCallbackFcn(app, @BextendButtonValueChanged, true);
            app.BextendButton.Text = 'B extend';
            app.BretractButton = uibutton(app.UIFigure, 'state');
            app.BretractButton.ValueChangedFcn = createCallbackFcn(app, @BretractButtonValueChanged, true);
            app.BretractButton.Text = 'B retract';
            app.tailSpinnerLabel = uilabel(app.UIFigure);
            app.tailSpinnerLabel.Text = 'tail';
            app.tailSpinner = uispinner(app.UIFigure);
            app.tailSpinner.Step = 10;
            app.tailSpinner.Limits = [0 178];
            app.tailSpinner.ValueChangedFcn = createCallbackFcn(app, @left_clawSpinnerValueChanged, true);
            app.tailSpinner.Value = 90;
            app.scan_LButton = uibutton(app.UIFigure, 'push');
            app.scan_LButton.ButtonPushedFcn = createCallbackFcn(app, @scan_LButtonPushed, true);
            app.scan_LButton.Text = 'scan_L';
            app.reach_LButton = uibutton(app.UIFigure, 'push');
            app.reach_LButton.ButtonPushedFcn = createCallbackFcn(app, @reach_LButtonPushed, true);
            app.reach_LButton.Text = 'reach_L';
            app.stopButton = uibutton(app.UIFigure, 'state');
            app.stopButton.ValueChangedFcn = createCallbackFcn(app, @stopButtonValueChanged, true);
            app.stopButton.Text = 'stop';
            app.RsonarEditFieldLabel = uilabel(app.UIFigure);
            app.RsonarEditFieldLabel.Text = 'R sonar';
            app.RsonarEditField = uieditfield(app.UIFigure, 'numeric');
            app.scan_RButton = uibutton(app.UIFigure, 'push');
            app.scan_RButton.ButtonPushedFcn = createCallbackFcn(app, @scan_RButtonPushed, true);
            app.scan_RButton.Text = 'scan_R';
            app.reach_RButton = uibutton(app.UIFigure, 'push');
            app.reach_RButton.ButtonPushedFcn = createCallbackFcn(app, @reach_RButtonPushed, true);
            app.reach_RButton.Text = 'reach_R';
            app.LsonarEditFieldLabel = uilabel(app.UIFigure);
            app.LsonarEditFieldLabel.Text = 'L sonar';
            app.LsonarEditField = uieditfield(app.UIFigure, 'numeric');
            app.leftopenButton = uibutton(app.UIFigure, 'push');
            app.leftopenButton.ButtonPushedFcn = createCallbackFcn(app, @leftopenButtonPushed, true);
            app.leftopenButton.Text = 'left open';
            app.leftcloseButton = uibutton(app.UIFigure, 'push');
            app.leftcloseButton.ButtonPushedFcn = createCallbackFcn(app, @leftcloseButtonPushed, true);
            app.leftcloseButton.Text = 'left close';
            app.rightopenButton = uibutton(app.UIFigure, 'push');
            app.rightopenButton.ButtonPushedFcn = createCallbackFcn(app, @rightopenButtonPushed, true);
            app.rightopenButton.Text = 'right open';
            app.rightcloseButton = uibutton(app.UIFigure, 'push');
            app.rightcloseButton.ButtonPushedFcn = createCallbackFcn(app, @rightcloseButtonPushed, true);
            app.rightcloseButton.Text = 'right close';
            app.tailtoleftButton = uibutton(app.UIFigure, 'push');
            app.tailtoleftButton.ButtonPushedFcn = createCallbackFcn(app, @tailtoleftButtonPushed, true);
            app.tailtoleftButton.Text = 'tail to left';
            app.tailtorightButton = uibutton(app.UIFigure, 'push');
            app.tailtorightButton.ButtonPushedFcn = createCallbackFcn(app, @tailtorightButtonPushed, true);
            app.tailtorightButton.Text = 'tail to right';
            app.basetomiddleButton = uibutton(app.UIFigure, 'push');
            app.basetomiddleButton.ButtonPushedFcn = createCallbackFcn(app, @basetomiddleButtonPushed, true);
            app.basetomiddleButton.Text = 'base to middle';
            app.tailtomiddleButton = uibutton(app.UIFigure, 'push');
            app.tailtomiddleButton.ButtonPushedFcn = createCallbackFcn(app, @tailtomiddleButtonPushed, true);
            app.tailtomiddleButton.Text = 'tail to middle';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.min_disEditFieldLabel = uilabel(app.UIFigure);
            app.min_disEditFieldLabel.Text = 'min_dis';
            app.min_disEditField = uieditfield(app.UIFigure, 'numeric');
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.IMU_angleEditFieldLabel = uilabel(app.UIFigure);
            app.IMU_angleEditFieldLabel.Text = 'IMU_angle';
            app.IMU_angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartCameraButton = uibutton(app.UIFigure, 'state');
            app.StartCameraButton.ValueChangedFcn = createCallbackFcn(app, @StartCameraButtonValueChanged, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.PatternRecognitionButton = uibutton(app.UIFigure, 'state');
            app.PatternRecognitionButton.Text = 'Pattern Recognition';
            app.CameraResultEditFieldLabel = uilabel(app.UIFigure);
            app.CameraResultEditFieldLabel.Visible = 'off';
            app.CameraResultEditFieldLabel.Text = 'Camera Result';
            app.CameraResultEditField = uieditfield(app.UIFigure, 'text');
            app.CameraResultEditField.Visible = 'off';
            app.CameraResultEditField.Value = 'Yes';
            app.Raw_img = uiimage(app.UIFigure);
            app.Color_img = uiimage(app.UIFigure);
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.ContinuousRunningButton = uibutton(app.UIFigure, 'state');
            app.ContinuousRunningButton.ValueChangedFcn = createCallbackFcn(app, @ContinuousRunningButtonValueChanged, true);
            app.ContinuousRunningButton.Text = 'Continuous Running';
            app.CurrentpositionSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentpositionSpinnerLabel.Text = 'Current position';
            app.CurrentpositionSpinner = uispinner(app.UIFigure);
            app.CurrentpositionSpinner.Limits = [0 7];
            app.min_angleEditFieldLabel = uilabel(app.UIFigure);
            app.min_angleEditFieldLabel.Text = 'min_angle';
            app.min_angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ButtonEditFieldLabel = uilabel(app.UIFigure);
            app.ButtonEditFieldLabel.Text = 'Button';
            app.ButtonEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalHW_monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end